classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        withthesameparametersLabel   matlab.ui.control.Label
        InputLamp_2                  matlab.ui.control.Lamp
        InputLamp                    matlab.ui.control.Lamp
        TestButton                   matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        MiguelAntnioGabrieldeAlmeidaFaria2019216809Label  matlab.ui.control.Label
        SanchoAmaralSimes2019217590Label  matlab.ui.control.Label
        MEISIMachineLearning20222023Label  matlab.ui.control.Label
        UniversityofCoimbraLabel     matlab.ui.control.Label
        DigitOCRLabel                matlab.ui.control.Label
        RetrainButton                matlab.ui.control.Button
        ConfigureOCRSystemPanel      matlab.ui.container.Panel
        FilterChooseFileButton       matlab.ui.control.Button
        ClassifierChooseFileButton   matlab.ui.control.Button
        FilterDropDownLabel          matlab.ui.control.Label
        ClassifierDropDownLabel      matlab.ui.control.Label
        IconInfoFilter               matlab.ui.control.Image
        IconInfoClassifier           matlab.ui.control.Image
        NNArchitectureDropDown       matlab.ui.control.DropDown
        NNArchitectureDropDownLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        filterNNFile = "";
        classifierNNFile = "";
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.IconInfoClassifier.Tooltip = "Name format: NN_<Train performance>_<validation performance>" + ...
                 "~<seed>_<number of layers>_<number of hidden neurons>_<activation function>_<train function>^<train type>_<learn rate>_<epochs>";
            app.IconInfoFilter.Tooltip = "Name format: NN_<Train performance>_<validation performance>~<seed>" + ...
                 "_<activation function>_<train function>^<train type>_<learn rate>_<epochs>";
            app.RetrainButton.Enable = "off";
            app.TestButton.Enable = "off";
            app.InputLamp.Color = "red";
            app.InputLamp_2.Color = "red";
            
        end

        % Value changed function: NNArchitectureDropDown
        function NNArchitectureDropDownValueChanged(app, event)
            value = app.NNArchitectureDropDown.Value;
            
            if strcmpi(value,'Filter + Classifier')
                app.FilterDropDownLabel.Visible = "on";
                app.IconInfoFilter.Visible = "on";
                app.FilterChooseFileButton.Visible = "on";
                OCRStatic.setGetOCRArchitecture(OCRConst.ARCHITECTURE_FILTER_AND_CLASSIFIER);
            elseif strcmpi(value,'Classifier')
                app.FilterDropDownLabel.Visible = "off";
                app.IconInfoFilter.Visible = "off";
                app.FilterChooseFileButton.Visible = "off";
                OCRStatic.setGetOCRArchitecture(OCRConst.ARCHITECTURE_CLASSIFIER);
            end
        end

        % Button pushed function: RetrainButton
        function RetrainButtonPushed(app, event)
             [fileName, filePath] = uigetfile("*.mat");
             totalPath = string(filePath) + string(fileName);

             if isfile(totalPath)
                 p = load(totalPath).P;
                 [nRows, nCols] = size(p);
                 classifierNN = load(app.classifierNNFile).classifier;
                 filterNN = 0;
    
                 if strcmpi(app.NNArchitectureDropDown.Value,'Filter + Classifier')
                     filterNN = load(app.filterNNFile).filter;
                     t = getFilterTargetData(nCols / OCRConst.N_CELLS_MPAPER);
                     filterNN.trainParam.showWindow = 1;
                     filterNN = train(filterNN, p, t);
                     save(app.filterNNFile.replace(".mat", "(R).mat"), "filterNN", "-mat")
                     p = filterNN(p);
                 end

                 classifierNN.trainParam.showWindow = 1;
                 t = getClassifierTargetData(nCols / OCRConst.N_CLASSES);
                 classifierNN = train(classifierNN, p, t);
                 save(app.classifierNNFile.replace(".mat", "(R).mat"), "classifierNN", "-mat");

             end
        end

        % Button pushed function: TestButton
        function pushTestButton(app, event)
            mpaperUI;
        end

        % Button pushed function: FilterChooseFileButton
        function FilterChooseFileButtonPushed(app, event)
            [fileName, filePath] = uigetfile("*.mat");

            totalPath = string(filePath) + string(fileName);
            if isfile(totalPath)
                app.filterNNFile = string(filePath) + string(fileName);
                OCRStatic.setGetFilterPath(app.filterNNFile);
                app.FilterChooseFileButton.Text = "Choose file - " + fileName;
                if app.classifierNNFile ~= ""
                    app.RetrainButton.Enable = "on";
                    app.TestButton.Enable = "on";
                    app.InputLamp.Color = "green";
                    app.InputLamp_2.Color = "green";
                end
            end
        end

        % Button pushed function: ClassifierChooseFileButton
        function ClassifierChooseFileButtonPushed(app, event)
            [fileName, filePath] = uigetfile("*.mat");

            totalPath = string(filePath) + string(fileName);
            if isfile(totalPath)
                app.classifierNNFile = string(filePath) + string(fileName);
                OCRStatic.setGetClassifierPath(app.classifierNNFile);
                app.ClassifierChooseFileButton.Text = "Choose file - " + fileName;
                if app.filterNNFile ~= "" || strcmpi(app.NNArchitectureDropDown.Value, 'Classifier')
                    app.RetrainButton.Enable = "on";
                    app.TestButton.Enable = "on";
                    app.InputLamp.Color = "green";
                    app.InputLamp_2.Color = "green";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfigureOCRSystemPanel = uipanel(app.UIFigure);
            app.ConfigureOCRSystemPanel.Title = 'Configure OCR System';
            app.NNArchitectureDropDownLabel = uilabel(app.ConfigureOCRSystemPanel);
            app.NNArchitectureDropDownLabel.Text = 'NN Architecture';
            app.NNArchitectureDropDown = uidropdown(app.ConfigureOCRSystemPanel);
            app.NNArchitectureDropDown.Items = {'Filter + Classifier', 'Classifier'};
            app.NNArchitectureDropDown.ValueChangedFcn = createCallbackFcn(app, @NNArchitectureDropDownValueChanged, true);
            app.NNArchitectureDropDown.Value = 'Filter + Classifier';
            app.IconInfoClassifier = uiimage(app.ConfigureOCRSystemPanel);
            app.IconInfoClassifier.ImageSource = fullfile(pathToMLAPP, 'info_icon.png');
            app.IconInfoFilter = uiimage(app.ConfigureOCRSystemPanel);
            app.IconInfoFilter.ImageSource = fullfile(pathToMLAPP, 'info_icon.png');
            app.ClassifierDropDownLabel = uilabel(app.ConfigureOCRSystemPanel);
            app.ClassifierDropDownLabel.Text = 'Classifier';
            app.FilterDropDownLabel = uilabel(app.ConfigureOCRSystemPanel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.ClassifierChooseFileButton = uibutton(app.ConfigureOCRSystemPanel, 'push');
            app.ClassifierChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifierChooseFileButtonPushed, true);
            app.ClassifierChooseFileButton.Text = 'Choose file - Empty';
            app.FilterChooseFileButton = uibutton(app.ConfigureOCRSystemPanel, 'push');
            app.FilterChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @FilterChooseFileButtonPushed, true);
            app.FilterChooseFileButton.Text = 'Choose file - Empty';
            app.RetrainButton = uibutton(app.UIFigure, 'push');
            app.RetrainButton.ButtonPushedFcn = createCallbackFcn(app, @RetrainButtonPushed, true);
            app.RetrainButton.Text = 'Retrain';
            app.DigitOCRLabel = uilabel(app.UIFigure);
            app.DigitOCRLabel.Text = 'Digit OCR';
            app.Panel = uipanel(app.UIFigure);
            app.UniversityofCoimbraLabel = uilabel(app.Panel);
            app.UniversityofCoimbraLabel.Text = 'University of Coimbra';
            app.MEISIMachineLearning20222023Label = uilabel(app.Panel);
            app.MEISIMachineLearning20222023Label.Text = 'MEI/SI/Machine Learning - 2022/2023';
            app.SanchoAmaralSimes2019217590Label = uilabel(app.Panel);
            app.SanchoAmaralSimes2019217590Label.Text = 'Sancho Amaral Simões, 2019217590';
            app.MiguelAntnioGabrieldeAlmeidaFaria2019216809Label = uilabel(app.Panel);
            app.MiguelAntnioGabrieldeAlmeidaFaria2019216809Label.Text = 'Miguel António Gabriel de Almeida Faria, 2019216809';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @pushTestButton, true);
            app.TestButton.Text = 'Test';
            app.InputLamp = uilamp(app.UIFigure);
            app.InputLamp_2 = uilamp(app.UIFigure);
            app.withthesameparametersLabel = uilabel(app.UIFigure);
            app.withthesameparametersLabel.Text = '(with the same parameters)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end